classdef AVIATION_IRIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel            matlab.ui.container.Panel
        GridLayout       matlab.ui.container.GridLayout
        GridLayout6      matlab.ui.container.GridLayout
        GridLayout7      matlab.ui.container.GridLayout
        ConnLamp         matlab.ui.control.Lamp
        ConnectionLabel  matlab.ui.control.Label
        Help             matlab.ui.control.Button
        GridLayout8      matlab.ui.container.GridLayout
        Simulate         matlab.ui.control.CheckBox
        GridLayout10     matlab.ui.container.GridLayout
        BCon             matlab.ui.control.Button
        BDiscon          matlab.ui.control.Button
        HardwarePanel    matlab.ui.container.Panel
        GridLayout11     matlab.ui.container.GridLayout
        Refresh          matlab.ui.control.Button
        PortLabel        matlab.ui.control.Label
        PortList         matlab.ui.control.DropDown
        GridLayout12     matlab.ui.container.GridLayout
        LEDSwitch        matlab.ui.control.Switch
        LEDLamp          matlab.ui.control.Lamp
        Axes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TIME_STEP = 0.07 % fixed time step of calling SendData function
        % define colors
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        s % serial port
        t % timer (for update GUI and send data)
        led_state = 0 % knobs value
        color_plot % color plot handlers
    end
    
    methods (Access = private)
        
        function updateGUI(app, led_state)
            % plot color value in GUI
            app.color_plot.YData = [app.color_plot.YData(2:end) led_state];
            % change color of GUI lamp
            app.LEDLamp.Color = [0 led_state 0]; % scale color values to 0..1 range
        end
        
        function sendData(app, led_state)
            if ~app.Simulate.Value
                % if output buffer is empty write values to serial port in asyncronous mode
                if app.s.BytesToOutput == 0
                    value_to_send = uint8(led_state); % scale color values to 255..0 uint8 range
                    fwrite(app.s, value_to_send, 'uint8', 'async'); % send prepared color values over serial to Arduino
                end
            end
        end
        
        
        function timerFunction(app, ~, ~)
            % Timer callback
            app.updateGUI(app.led_state);
            app.sendData(app.led_state);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set up swith values
            app.LEDSwitch.ItemsData = [1 1022];
            
            % read knob values into 1x3 array
            app.led_state = app.LEDSwitch.Value;
            % define time vector for plotting
            time = (0 : app.TIME_STEP : 10)';
            
            % init and set up axes
            app.Axes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.Axes);
            
            % define color order fot plotting
            app.Axes.ColorOrder = app.GREEN_COLOR;
            
            app.color_plot = plot(app.Axes, time, zeros(length(time), 1), 'LineWidth', 2);
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.timerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.TIME_STEP;
            app.t.BusyMode = 'drop';
            
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.t.running == "on"
                stop(app.t);
                if ~app.Simulate.Value
                    stopasync(app.s)
                    fclose(app.s);
                end
                app.ConnLamp.Color = app.RED_COLOR;
                app.Simulate.Enable = 'on';
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if app.t.running == "off"
                if ~app.Simulate.Value
                    app.s = serial(app.PortList.Value);
                    fopen(app.s);
                end
                start(app.t);
                app.ConnLamp.Color = app.GREEN_COLOR;
                app.Simulate.Enable = 'off';
            end
        end

        % Button pushed function: Refresh
        function RefreshPushed(app, event)
            if license('test', 'instr_control_toolbox')
                % delete all serial port objets from MATLAB memory
                % (Instrument Control Toolbox required)
                delete(instrfind);
                % looking for available serial ports
                % (Instrument Control Toolbox required)
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
        end

        % Value changed function: Simulate
        function SimulateValueChanged(app, event)
            app.BDisconButtonPushed();
            value = app.Simulate.Value;
            app.HardwarePanel.Visible = ~value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.BDisconButtonPushed();
            delete(app);
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            open led_doc.mlx
        end

        % Value changed function: LEDSwitch
        function LEDSwitchValueChanged(app, event)
            app.led_state = app.LEDSwitch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'LED Control';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ConnLamp = uilamp(app.GridLayout7);
            app.ConnectionLabel = uilabel(app.GridLayout7);
            app.ConnectionLabel.Text = 'Connection';
            app.Help = uibutton(app.GridLayout7, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Text = 'Help';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.Simulate = uicheckbox(app.GridLayout8);
            app.Simulate.ValueChangedFcn = createCallbackFcn(app, @SimulateValueChanged, true);
            app.Simulate.Text = 'Simulate hardware';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.BCon = uibutton(app.GridLayout10, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.GridLayout10, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.HardwarePanel = uipanel(app.GridLayout8);
            app.HardwarePanel.Title = 'Hardware';
            app.GridLayout11 = uigridlayout(app.HardwarePanel);
            app.Refresh = uibutton(app.GridLayout11, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel = uilabel(app.GridLayout11);
            app.PortLabel.Text = 'Port';
            app.PortList = uidropdown(app.GridLayout11);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.GridLayout12 = uigridlayout(app.GridLayout8);
            app.LEDSwitch = uiswitch(app.GridLayout12, 'slider');
            app.LEDSwitch.ValueChangedFcn = createCallbackFcn(app, @LEDSwitchValueChanged, true);
            app.LEDSwitch.Value = 'On';
            app.LEDLamp = uilamp(app.GridLayout12);
            app.Axes = uiaxes(app.GridLayout);
            title(app.Axes, 'LED State')
            xlabel(app.Axes, 'Time, sec')
            app.Axes.XLim = [0 10];
            app.Axes.YLim = [0 1];
            app.Axes.YTick = [0 1];
            app.Axes.YTickLabel = {'OFF'; 'ON'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AVIATION_IRIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end