classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataVisualizationPanel          matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        ArduinoSerialConnectionPanel    matlab.ui.container.Panel
        HardwarePanel                   matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Refresh                         matlab.ui.control.Button
        PortLabel                       matlab.ui.control.Label
        PortList                        matlab.ui.control.DropDown
        ArduinoConnectionLampLabel      matlab.ui.control.Label
        ArduinoConnectionLamp           matlab.ui.control.Lamp
        BCon                            matlab.ui.control.Button
        BDiscon                         matlab.ui.control.Button
        DataControlProgramStatusPanel   matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        ProgramLogTextAreaLabel         matlab.ui.control.Label
        ProgramLogTextArea              matlab.ui.control.TextArea
        FlowCharacteristicsPanel        matlab.ui.container.Panel
        WindSpeedUnitsButtonGroup       matlab.ui.container.ButtonGroup
        KnotsButton                     matlab.ui.control.RadioButton
        msButton                        matlab.ui.control.RadioButton
        mphButton                       matlab.ui.control.RadioButton
        ftsButton                       matlab.ui.control.RadioButton
        WindSpeedGauge                  matlab.ui.control.Gauge
        WindSpeedGaugeLabel             matlab.ui.control.Label
        AngleofAttackControlPanel       matlab.ui.container.Panel
        TargetAoAKnob                   matlab.ui.control.Knob
        TargetAoAEditField              matlab.ui.control.NumericEditField
        TargetAoAEditFieldLabel         matlab.ui.control.Label
        AoAControlSwitchLabel           matlab.ui.control.Label
        AoAControlSwitch                matlab.ui.control.ToggleSwitch
        CurrentAngleofAttackGauge       matlab.ui.control.SemicircularGauge
        CurrentAngleofAttackGaugeLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        READ_TIME_STEP = 0.01
        WRITE_TIME_STEP = 2
        s % serial port
        t % timer (for update GUI and send data)
        t2
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        servo_AoA
        data
        startTime
        control_on
        last_AoA
        lift
        drag
    end
    
    methods (Access = private)
        function updateGUI(app, led_state)
            % plot color value in GUI
            app.color_plot.YData = [app.color_plot.YData(2:end) led_state];
            % change color of GUI lamp
            app.LEDLamp.Color = [0 led_state 0]; % scale color values to 0..1 range
        end
        function sendData(app, servo_control)
            % if output buffer is empty write values to serial port in asyncronous mode
            temp=int16((servo_control+150)/0.2933);
                if(temp>0&&temp<1023)
                    writeline(app.s, num2str(temp)); % send prepared color values over serial to Arduino
                end
        end
        function readData(app)
            % if output buffer is empty write values to serial port in asyncronous mode
            if app.s.NumBytesAvailable~=0
                temp=str2num(readline(app.s));
                time=datetime('now')-app.startTime;
                addpoints(app.lift,datenum(time),temp(1)+temp(2)+temp(3))
                addpoints(app.drag,datenum(time),temp(4)+temp(5))
                dataline=[seconds(time) app.last_AoA temp];
                disp(dataline) % Debugging tool for displaying incoming
                % load cell data
                app.data=[app.data; dataline];
                app.UIAxes2.XLim = datenum([time-seconds(15) time]);
                app.UIAxes1.XLim = datenum([time-seconds(15) time]);
                datetick(app.UIAxes2,'x','MM:SS','keeplimits')
                datetick(app.UIAxes1,'x','MM:SS','keeplimits')
            end
        end
        function readTimerFunction(app, ~, ~)
            % Timer callback
            %             app.updateGUI(0.5);
            app.readData()
        end
        function writeTimerFunction(app, ~, ~)
            if app.control_on=="On"
            disp("attempting command")
            if app.last_AoA~=app.servo_AoA
            disp("executing command")
            app.sendData(app.servo_AoA);
            app.last_AoA=app.servo_AoA;
            app.CurrentAngleofAttackGauge.Value=app.last_AoA;
            %flush(app.s)
            end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % define time vector for plotting
            %            time = (0 : app.TIME_STEP : 10)';
            app.startTime=datetime("now");
            %             % init and set up axes
            %             app.Axes.Toolbar.Visible = 'off';
            %             disableDefaultInteractivity(app.Axes);
            %
            %             % define color order fot plotting
            %             app.Axes.ColorOrder = app.GREEN_COLOR;
            %
            %             app.color_plot = plot(app.Axes, time, zeros(length(time), 1), 'LineWidth', 2);
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.readTimerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.READ_TIME_STEP;
            app.t.BusyMode = 'drop';
            app.t2 = timer;
            app.t2.TimerFcn = @app.writeTimerFunction;
            app.t2.ExecutionMode = 'fixedRate';
            app.t2.Period = app.WRITE_TIME_STEP;
            app.t2.BusyMode = 'drop';
            % Initial Settings
            app.lift = animatedline(app.UIAxes1,'Color','b','LineWidth',3);
            app.UIAxes1.YGrid = 'on';
            app.UIAxes1.YLim = [-750 750];
            app.drag = animatedline(app.UIAxes2,'Color','r','LineWidth',3);
            app.UIAxes2.YGrid = 'on';
            app.UIAxes2.YLim = [-750 750];
            app.data=[];
            app.servo_AoA=app.TargetAoAEditField.Value;
            app.last_AoA=app.servo_AoA;
            app.control_on=app.AoAControlSwitch.Value;
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = serialportlist();
                app.PortList.Items = p;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.BDisconButtonPushed();
            delete(app)
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            if license('test', 'instr_control_toolbox')
                p = serialportlist();
                app.PortList.Items = p;
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if app.t.running == "off"
                app.s = serialport(app.PortList.Value,115200);
                configureTerminator(app.s,"CR")
                start(app.t);
                start(app.t2)
                app.ArduinoConnectionLamp.Color = app.GREEN_COLOR;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.t.running == "on"
                stop(app.t);
                stop(app.t2);
                delete(app.s);
            end
            app.ArduinoConnectionLamp.Color = app.RED_COLOR;
        end

        % Value changed function: TargetAoAEditField
        function TargetAoAEditFieldValueChanged(app, event)
            value=app.TargetAoAEditField.Value;
            app.servo_AoA = int16((app.TargetAoAEditField.Value+150)/0.2933);
            app.TargetAoAKnob.Value= value;
        end

        % Value changed function: TargetAoAKnob
        function TargetAoAKnobValueChanged(app, event)
            value = app.TargetAoAKnob.Value;
            %app.servo_AoA=int16((app.TargetAoAEditField.Value+150)/0.2933);
            app.servo_AoA=value;
            app.TargetAoAEditField.Value=value;
        end

        % Value changed function: AoAControlSwitch
        function AoAControlSwitchValueChanged(app, event)
            app.control_on = app.AoAControlSwitch.Value;
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            if ~isempty(app.data)
            csvwrite(""+datestr(now,'dd_mmm_yy HHMM')+"HRS.csv",app.data)    
            app.ProgramLogTextArea.Value="Data Successfully Captured in "+datestr(now,'dd_mmm_yy HHMM')+"HRS.csv";
            else
            app.ProgramLogTextArea.Value="No Data Detected";
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.data=[];
            app.ProgramLogTextArea.Value="Data Cleared";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataVisualizationPanel = uipanel(app.UIFigure);
            app.DataVisualizationPanel.Title = 'Data Visualization';
            app.UIAxes2 = uiaxes(app.DataVisualizationPanel);
            title(app.UIAxes2, 'Total Drag Force')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Force (1x10^{-2} N)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes1 = uiaxes(app.DataVisualizationPanel);
            title(app.UIAxes1, 'Total Lift Force')
            xlabel(app.UIAxes1, 'Time')
            ylabel(app.UIAxes1, 'Force (1x10^{-2} N)')
            zlabel(app.UIAxes1, 'Z')
            app.ArduinoSerialConnectionPanel = uipanel(app.UIFigure);
            app.ArduinoSerialConnectionPanel.Title = 'Arduino Serial Connection';
            app.HardwarePanel = uipanel(app.ArduinoSerialConnectionPanel);
            app.HardwarePanel.Title = 'Hardware';
            app.GridLayout11 = uigridlayout(app.HardwarePanel);
            app.Refresh = uibutton(app.GridLayout11, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel = uilabel(app.GridLayout11);
            app.PortLabel.Text = 'Port';
            app.PortList = uidropdown(app.GridLayout11);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.ArduinoConnectionLampLabel = uilabel(app.ArduinoSerialConnectionPanel);
            app.ArduinoConnectionLampLabel.Text = 'Arduino Connection';
            app.ArduinoConnectionLamp = uilamp(app.ArduinoSerialConnectionPanel);
            app.BCon = uibutton(app.ArduinoSerialConnectionPanel, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.ArduinoSerialConnectionPanel, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.DataControlProgramStatusPanel = uipanel(app.UIFigure);
            app.DataControlProgramStatusPanel.Title = 'Data Control, Program Status';
            app.SaveDataButton = uibutton(app.DataControlProgramStatusPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.ClearDataButton = uibutton(app.DataControlProgramStatusPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.ProgramLogTextAreaLabel = uilabel(app.DataControlProgramStatusPanel);
            app.ProgramLogTextAreaLabel.Text = 'Program Log';
            app.ProgramLogTextArea = uitextarea(app.DataControlProgramStatusPanel);
            app.ProgramLogTextArea.Editable = 'off';
            app.FlowCharacteristicsPanel = uipanel(app.UIFigure);
            app.FlowCharacteristicsPanel.Title = 'Flow Characteristics';
            app.WindSpeedUnitsButtonGroup = uibuttongroup(app.FlowCharacteristicsPanel);
            app.WindSpeedUnitsButtonGroup.Title = 'Wind Speed Units';
            app.KnotsButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.KnotsButton.Text = 'Knots';
            app.KnotsButton.Value = true;
            app.msButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.msButton.Text = 'm/s';
            app.mphButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.mphButton.Text = 'mph';
            app.ftsButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.ftsButton.Text = 'ft/s';
            app.WindSpeedGauge = uigauge(app.FlowCharacteristicsPanel, 'circular');
            app.WindSpeedGauge.Limits = [0 35];
            app.WindSpeedGaugeLabel = uilabel(app.FlowCharacteristicsPanel);
            app.WindSpeedGaugeLabel.Text = 'Wind Speed';
            app.AngleofAttackControlPanel = uipanel(app.UIFigure);
            app.AngleofAttackControlPanel.Title = 'Angle of Attack Control';
            app.TargetAoAKnob = uiknob(app.AngleofAttackControlPanel, 'continuous');
            app.TargetAoAKnob.Limits = [-120 120];
            app.TargetAoAKnob.ValueChangedFcn = createCallbackFcn(app, @TargetAoAKnobValueChanged, true);
            app.TargetAoAEditField = uieditfield(app.AngleofAttackControlPanel, 'numeric');
            app.TargetAoAEditField.Limits = [-120 120];
            app.TargetAoAEditField.ValueDisplayFormat = '%.2f';
            app.TargetAoAEditField.ValueChangedFcn = createCallbackFcn(app, @TargetAoAEditFieldValueChanged, true);
            app.TargetAoAEditFieldLabel = uilabel(app.AngleofAttackControlPanel);
            app.TargetAoAEditFieldLabel.Text = 'Target AoA';
            app.AoAControlSwitchLabel = uilabel(app.AngleofAttackControlPanel);
            app.AoAControlSwitchLabel.Text = 'AoA Control';
            app.AoAControlSwitch = uiswitch(app.AngleofAttackControlPanel, 'toggle');
            app.AoAControlSwitch.ValueChangedFcn = createCallbackFcn(app, @AoAControlSwitchValueChanged, true);
            app.CurrentAngleofAttackGauge = uigauge(app.AngleofAttackControlPanel, 'semicircular');
            app.CurrentAngleofAttackGauge.Limits = [-90 90];
            app.CurrentAngleofAttackGaugeLabel = uilabel(app.AngleofAttackControlPanel);
            app.CurrentAngleofAttackGaugeLabel.Text = 'Current Angle of Attack';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end